classdef livemRNAApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LivemRNAAnalysisUIFigure        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SelectprojectMenu               matlab.ui.container.Menu
        HelpMenu                        matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        ExportDataTab                   matlab.ui.container.Tab
        RundataexportButton             matlab.ui.control.Button
        NuclearchannelprojectiontypeDropDownLabel  matlab.ui.control.Label
        NuclearchannelprojectiontypeDropDown  matlab.ui.control.DropDown
        FilteringTab                    matlab.ui.container.Tab
        highprecisionCheckBox           matlab.ui.control.CheckBox
        keeppoolCheckBox                matlab.ui.control.CheckBox
        displayfiguresCheckBox          matlab.ui.control.CheckBox
        numberofframesEditFieldLabel    matlab.ui.control.Label
        numberofframesEditField         matlab.ui.control.NumericEditField
        runFilterMovie                  matlab.ui.control.Button
        FilterDropDownLabel             matlab.ui.control.Label
        FilterDropDown                  matlab.ui.control.DropDown
        numberofworkersEditFieldLabel   matlab.ui.control.Label
        numberofworkersEditField        matlab.ui.control.NumericEditField
        usesthedefaultvaluesforfilteringLabel  matlab.ui.control.Label
        filterAxes                      matlab.ui.control.UIAxes
        ShowpreviewCheckBox             matlab.ui.control.CheckBox
        FrameSliderLabel                matlab.ui.control.Label
        FrameSlider                     matlab.ui.control.Slider
        ChannelDropDownLabel            matlab.ui.control.Label
        ChannelDropDown                 matlab.ui.control.DropDown
        zSliderLabel                    matlab.ui.control.Label
        zSlider                         matlab.ui.control.Slider
        Sigma1SpinnerLabel              matlab.ui.control.Label
        firstsigma                      matlab.ui.control.Spinner
        Sigma2SpinnerLabel              matlab.ui.control.Label
        secondsigma                     matlab.ui.control.Spinner
        SegmentationTab                 matlab.ui.container.Tab
        keeppoolCheckBox_2              matlab.ui.control.CheckBox
        displayfiguresCheckBox_2        matlab.ui.control.CheckBox
        runSegmentSpots                 matlab.ui.control.Button
        numberofworkersEditField_2Label  matlab.ui.control.Label
        numberofworkersEditField_2      matlab.ui.control.NumericEditField
        thresholdGUICheckBox            matlab.ui.control.CheckBox
        FrameEditFieldLabel             matlab.ui.control.Label
        firstFrameEditField             matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        LastFrameEditField              matlab.ui.control.NumericEditField
        numberofshadowsDropDownLabel    matlab.ui.control.Label
        numberofshadowsDropDown         matlab.ui.control.DropDown
        IntegralzCheckBox               matlab.ui.control.CheckBox
        integrationscaleEditFieldLabel  matlab.ui.control.Label
        integrationscaleEditField       matlab.ui.control.NumericEditField
        Thresholdch1EditFieldLabel      matlab.ui.control.Label
        Thresholdch1EditField           matlab.ui.control.NumericEditField
        Thresholdch2EditFieldLabel      matlab.ui.control.Label
        Thresholdch2EditField           matlab.ui.control.NumericEditField
        ifapplicableLabel               matlab.ui.control.Label
        segAxes                         matlab.ui.control.UIAxes
        TrackingTab                     matlab.ui.container.Tab
        runTrackmRNADynamics            matlab.ui.control.Button
        AxisregistrationTab             matlab.ui.container.Tab
        runFindAPAxis                   matlab.ui.control.Button
        runAddParticlePosition          matlab.ui.control.Button
        CorrectAxisCheckBox             matlab.ui.control.CheckBox
        ManualAlignmentCheckBox         matlab.ui.control.CheckBox
        NuclearsegmentationandtrackingTab  matlab.ui.container.Tab
        DataStatusTab                   matlab.ui.container.Tab
        Panel                           matlab.ui.container.Panel
        PrefixEditField_4Label          matlab.ui.control.Label
        PrefixEditField_4               matlab.ui.control.EditField
        DataexportedLabel               matlab.ui.control.Label
        DataexportedLamp                matlab.ui.control.Lamp
        DatafilteredLampLabel           matlab.ui.control.Label
        DatafilteredLamp                matlab.ui.control.Lamp
        DatasegmentedLampLabel          matlab.ui.control.Label
        DatasegmentedLamp               matlab.ui.control.Lamp
        DatatrackedLampLabel            matlab.ui.control.Label
        DatatrackedLamp                 matlab.ui.control.Lamp
        LoadFrameInfoButton             matlab.ui.control.Button
        ExperimenttypeTextAreaLabel     matlab.ui.control.Label
        Experimenttype                  matlab.ui.control.TextArea
        RefreshButton                   matlab.ui.control.Button
        LoadlogfileButton               matlab.ui.control.Button
        Channel1Label                   matlab.ui.control.Label
        ch1Label                        matlab.ui.control.TextArea
        Channel3Label                   matlab.ui.control.Label
        ch3Label                        matlab.ui.control.TextArea
        Channel2Label                   matlab.ui.control.Label
        ch2Label                        matlab.ui.control.TextArea
        APaxisregisteredLampLabel       matlab.ui.control.Label
        APaxisregisteredLamp            matlab.ui.control.Lamp
        ParticlesregisteredtoaxisLampLabel  matlab.ui.control.Label
        ParticlesregisteredtoaxisLamp   matlab.ui.control.Lamp
    end

    
    methods (Access = private)
        
        function setPipelineStatus(app, prefix_input)
            
            app.PrefixEditField_4.Value = prefix_input;
            
            [SourcePath,FISHPath,DropboxFolder,MS2CodePath, PreProcPath,...
                Folder, Prefix, ExperimentType,Channel1,Channel2,OutputFolder, Channel3] = readMovieDatabase(prefix_input);
            
                                
                if exist([DropboxFolder,filesep,'Particles.mat'], 'file')
                    app.DatatrackedLamp.Color = [0 1 0];
                end
                
                if exist([DropboxFolder,filesep,'Spots.mat'], 'file')
                    app.DatasegmentedLamp.Color = [0 1 0];
                end
                
                if exist([DropboxFolder,filesep,'FrameInfo.mat'], 'file')
                    app.DataexportedLamp.Color = [0 1 0];
                end
                
                if exist([FISHPath,filesep,Prefix,'_'], 'dir')
                    app.DatafilteredLamp.Color = [0 1 0];
                end
                
                app.Experimenttype.Value = ExperimentType;
                app.ch1Label.Value = Channel1;
                app.ch2Label.Value = Channel2;
                app.ch3Label.Value = Channel3;
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            if ~isempty(varargin)
                
                prefix_input = varargin{1};
                               
                setPipelineStatus(app, prefix_input)
                
            end
            
            imshow(zeros(512), 'Border','Tight','Parent', app.filterAxes)

        end

        % Button pushed function: runFilterMovie
        function runFilterMoviePushed(app, event)
            
            prefix = app.PrefixEditField_4.Value;
            options = {};
            
            %optional settings
            if app.highprecisionCheckBox.Value
                options = [options, 'highPrecision'];
            end
            if app.keeppoolCheckBox.Value
                options = [options, 'keepPool'];
            end
            if app.displayfiguresCheckBox.Value
                options = [options, 'displayFigures'];
            end
            if app.numberofframesEditField.Value ~= Inf
                options = [options, 'numFrames',app.numberofframesEditField.Value]; 
            end
            
            if app.firstsigma.Value
                if strcmpi(app.FilterDropDown.Value,'Difference_of_Gaussian') ||  strcmpi(app.FilterDropDown.Value,'Structure_largest') ||  strcmpi(app.FilterDropDown.Value,'Structure_smallest')
                    options = [options, 'customFilter', app.FilterDropDown.Value, {app.firstsigma.Value, app.secondsigma.Value}];
                else
                    %sigma's double-celled because matlab ignores the first cell for some reason
                    options = [options, 'customFilter', app.FilterDropDown.Value, {{app.firstsigma.Value}}];
                end
            end
                
            options = [options, 'nWorkers',app.numberofworkersEditField.Value];
                            
            filterMovie(prefix, options{:}, 'app', app.filterAxes)          

        end

        % Menu selected function: SelectprojectMenu
        function SelectprojectMenuSelected(app, event)
            
            [SourcePath,FISHPath,DropboxFolder,MS2CodePath, PreProcPath,...
            Folder, Prefix, ExperimentType,Channel1,Channel2,OutputFolder, Channel3] = readMovieDatabase('');
            
            
            setPipelineStatus(app, Prefix)


        end

        % Button pushed function: runSegmentSpots
        function runSegmentSpotsPushed(app, event)
            
            prefix = app.PrefixEditField_4.Value;
            threshold = -1;
            options = {};
            
            
             [SourcePath,FISHPath,DropboxFolder,MS2CodePath, PreProcPath,...
                Folder, Prefix, ExperimentType,Channel1,Channel2,OutputFolder, Channel3] = readMovieDatabase(prefix);
            
             %optional settings
            if app.thresholdGUICheckBox.Value
                options = [options, 'autoThresh'];
            else
                if strcmpi(ExperimentType, '2spot2color')
                    threshold = [app.Thresholdch1EditField.Value, app.Thresholdch2EditField.Value];
                else 
                    threshold = app.Thresholdch1EditField.Value;
                end
            end      
            if app.keeppoolCheckBox_2.Value
                options = [options, 'keepPool'];
            end
            if app.displayfiguresCheckBox_2.Value
                options = [options, 'displayFigures'];
            end
            if app.LastFrameEditField.Value ~= Inf 
                options = [options, 'Frames',app.LastFrameEditField.Value]; 
            end
            if app.IntegralzCheckBox.Value
                options = [options, 'IntegralZ'];
            end
                
            options = [options, 'nWorkers',app.numberofworkersEditField_2.Value];          
            options = [options, 'intScale', app.integrationscaleEditField.Value ];            
            options = [options, 'Shadows', str2double(app.numberofshadowsDropDown.Value)];
            options = [options, 'firstFrame',app.firstFrameEditField.Value]; 
            
            segmentSpots(prefix, threshold, options{:})  
            
        end

        % Button pushed function: runTrackmRNADynamics
        function runTrackmRNADynamicsButtonPushed(app, event)
            
            prefix = app.PrefixEditField_4.Value;
            threshold1 = 0;
            threshold2 = 0;
            
            if app.twoChannelCheckBox.Value
                threshold1 = [0, 0];
                threshold2 = [0, 0];
            end
            
            TrackmRNADynamics(prefix, threshold1, threshold2)
            
        end

        % Button pushed function: RundataexportButton
        function RundataexportButtonPushed(app, event)
            
            options = {};
            
            options = [options, app.NuclearchannelprojectiontypeDropDown.Value];
            
            Prefix = ExportDataForFISH(options);
            
            setPipelineStatus(app, Prefix)
            
        end

        % Value changed function: ChannelDropDown, FilterDropDown, 
        % FrameSlider, ShowpreviewCheckBox, zSlider
        function updatePreview(app, event)
            
            if app.ShowpreviewCheckBox.Value
               
                
                [SourcePath,FISHPath,DropboxFolder,MS2CodePath, PreProcPath,...
                Folder, Prefix, ExperimentType,Channel1,Channel2,OutputFolder, Channel3] = readMovieDatabase(app.PrefixEditField_4.Value);
            
                load([DropboxFolder, filesep, Prefix, filesep, 'FrameInfo.mat']);
                zMax = FrameInfo(1).NumberSlices + 2;
                app.zSlider.Limits = [1, zMax];
                pixelSize = FrameInfo(1).PixelSize * 1000; %nm
                frameMax = length(FrameInfo);
                app.FrameSlider.Limits = [1, frameMax];
                
                coatChannel = getCoatChannel(ExperimentType, Channel1, Channel2);
                
                frame = round(app.FrameSlider.Value);
                z = round(app.zSlider.Value);
                channelIndex = str2double(app.ChannelDropDown.Value);
                
                if strcmpi(ExperimentType, 'inputoutput') || strcmpi(ExperimentType, 'lattice')
                  nameSuffix= ['_ch', iIndex(coatChannel, 2)];
                else
                  nameSuffix = ['_ch', iIndex(channelIndex, 2)];
                end
                 
                imPath = [PreProcPath, filesep, Prefix, filesep, Prefix, '_', iIndex(frame, 3), '_z',...
                iIndex(z, 2), nameSuffix, '.tif'];
            
                im = double(imread(imPath));
                
                filterSize = round(2000/pixelSize); %2000nm seems to be a good size empirically -AR
                
                filterType = app.FilterDropDown.Value;
                
                use2sigmas = 0;
                
                 if strcmp(filterType, 'Difference_of_Gaussian') || ...
                    strcmp(filterType, 'Structure_largest') || ...
                    strcmp(filterType, 'Structure_smallest')
                
                        use2sigmas = 1;
                        
                 end
                
                if use2sigmas
                    
                    s = {app.firstsigma.Value, app.secondsigma.Value};
                
                    if ~s{1} || ~s{2}
                        
                       s = {1, round(42000/pixelSize)}; %good initial conditions determined empirically
                       
                    end
                    
                else
                    
                    if app.firstsigma.Value
                        s = {app.firstsigma.Value};
                    else
                        s = {3}; %seems like a good default size to prevent errors?
                    end 
                    
                    filterSize = s{1}*3;
                    
                end
                       
                
                fIm = filterImage(im, filterType, s, filterSize);
                
                
                imshow(fIm , [median(fIm(:)), max(fIm(:))],'Border','Tight', 'Parent', app.filterAxes, 'InitialMagnification', 'fit')    
                
            else
                
                imshow(zeros(512), 'Border','Tight','Parent', app.filterAxes,'InitialMagnification', 'fit')
                
            end
            
        end

        % Callback function: PrefixEditField_4, RefreshButton
        function prefixLoaded(app, event)
               
            
            setPipelineStatus(app, app.PrefixEditField_4.Value)
                
                
        end

        % Button pushed function: runFindAPAxis
        function runFindAPAxisButtonPushed(app, event)
            
            prefix = app.PrefixEditField_4.Value;
            
            if app.CorrectAxisCheckBox.Value 
                FindAPAxisFullEmbryo(prefix, 'CorrectAxis');
            else
                FindAPAxisFullEmbryo(prefix);
            end
            
        end

        % Button pushed function: runAddParticlePosition
        function runAddParticlePositionButtonPushed(app, event)
            
            prefix = app.PrefixEditField_4.Value;
            if app.ManualAlignmentCheckBox.Value
                AddParticlePosition(prefix, 'ManualAlignment');
            else
                AddParticlePosition(prefix);
            end
           
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LivemRNAAnalysisUIFigure = uifigure;
            app.LivemRNAAnalysisUIFigure.Name = 'Live mRNA Analysis';
            app.FileMenu = uimenu(app.LivemRNAAnalysisUIFigure);
            app.FileMenu.Text = 'File';
            app.SelectprojectMenu = uimenu(app.FileMenu);
            app.SelectprojectMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectprojectMenuSelected, true);
            app.SelectprojectMenu.Text = 'Select project...';
            app.HelpMenu = uimenu(app.LivemRNAAnalysisUIFigure);
            app.HelpMenu.Text = 'Help';
            app.TabGroup = uitabgroup(app.LivemRNAAnalysisUIFigure);
            app.ExportDataTab = uitab(app.TabGroup);
            app.ExportDataTab.Title = 'Export Data';
            app.RundataexportButton = uibutton(app.ExportDataTab, 'push');
            app.RundataexportButton.ButtonPushedFcn = createCallbackFcn(app, @RundataexportButtonPushed, true);
            app.RundataexportButton.Text = 'Run data export ';
            app.NuclearchannelprojectiontypeDropDownLabel = uilabel(app.ExportDataTab);
            app.NuclearchannelprojectiontypeDropDownLabel.Text = 'Nuclear channel projection type';
            app.NuclearchannelprojectiontypeDropDown = uidropdown(app.ExportDataTab);
            app.NuclearchannelprojectiontypeDropDown.Items = {'maxprojection', 'medianprojection', 'middleprojection'};
            app.NuclearchannelprojectiontypeDropDown.Value = 'maxprojection';
            app.FilteringTab = uitab(app.TabGroup);
            app.FilteringTab.Title = 'Filtering';
            app.highprecisionCheckBox = uicheckbox(app.FilteringTab);
            app.highprecisionCheckBox.Text = 'high precision';
            app.keeppoolCheckBox = uicheckbox(app.FilteringTab);
            app.keeppoolCheckBox.Text = 'keep pool';
            app.displayfiguresCheckBox = uicheckbox(app.FilteringTab);
            app.displayfiguresCheckBox.Text = 'display figures';
            app.displayfiguresCheckBox.Value = true;
            app.numberofframesEditFieldLabel = uilabel(app.FilteringTab);
            app.numberofframesEditFieldLabel.Text = 'number of frames';
            app.numberofframesEditField = uieditfield(app.FilteringTab, 'numeric');
            app.numberofframesEditField.Limits = [1 Inf];
            app.numberofframesEditField.RoundFractionalValues = 'on';
            app.numberofframesEditField.Value = Inf;
            app.runFilterMovie = uibutton(app.FilteringTab, 'push');
            app.runFilterMovie.ButtonPushedFcn = createCallbackFcn(app, @runFilterMoviePushed, true);
            app.runFilterMovie.Text = 'Run filtering';
            app.FilterDropDownLabel = uilabel(app.FilteringTab);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.FilteringTab);
            app.FilterDropDown.Items = {'Difference_of_Gaussian', 'Identity', 'Maximum', 'Laplacian', 'Mean', 'Hessian_largest', 'Hessian_smallest', 'Minimum', 'Median', 'Gaussian_blur'};
            app.FilterDropDown.ValueChangedFcn = createCallbackFcn(app, @updatePreview, true);
            app.FilterDropDown.Value = 'Difference_of_Gaussian';
            app.numberofworkersEditFieldLabel = uilabel(app.FilteringTab);
            app.numberofworkersEditFieldLabel.Text = 'number of workers';
            app.numberofworkersEditField = uieditfield(app.FilteringTab, 'numeric');
            app.numberofworkersEditField.Value = 8;
            app.usesthedefaultvaluesforfilteringLabel = uilabel(app.FilteringTab);
            app.usesthedefaultvaluesforfilteringLabel.Text = '0 uses the default values for filtering';
            app.filterAxes = uiaxes(app.FilteringTab);
            app.filterAxes.XTick = [];
            app.filterAxes.YTick = [];
            app.filterAxes.ZTick = [];
            app.ShowpreviewCheckBox = uicheckbox(app.FilteringTab);
            app.ShowpreviewCheckBox.ValueChangedFcn = createCallbackFcn(app, @updatePreview, true);
            app.ShowpreviewCheckBox.Text = 'Show preview';
            app.FrameSliderLabel = uilabel(app.FilteringTab);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.FilteringTab);
            app.FrameSlider.Limits = [1 100];
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @updatePreview, true);
            app.FrameSlider.MinorTicks = [];
            app.FrameSlider.Value = 2;
            app.ChannelDropDownLabel = uilabel(app.FilteringTab);
            app.ChannelDropDownLabel.Text = 'Channel ';
            app.ChannelDropDown = uidropdown(app.FilteringTab);
            app.ChannelDropDown.Items = {'1', '2'};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @updatePreview, true);
            app.ChannelDropDown.Value = '1';
            app.zSliderLabel = uilabel(app.FilteringTab);
            app.zSliderLabel.Text = 'z';
            app.zSlider = uislider(app.FilteringTab);
            app.zSlider.Limits = [1 30];
            app.zSlider.ValueChangedFcn = createCallbackFcn(app, @updatePreview, true);
            app.zSlider.MinorTicks = [];
            app.zSlider.Value = 10;
            app.Sigma1SpinnerLabel = uilabel(app.FilteringTab);
            app.Sigma1SpinnerLabel.Text = 'Sigma 1';
            app.firstsigma = uispinner(app.FilteringTab);
            app.firstsigma.Step = 0.25;
            app.firstsigma.Limits = [0 Inf];
            app.Sigma2SpinnerLabel = uilabel(app.FilteringTab);
            app.Sigma2SpinnerLabel.Text = 'Sigma 2';
            app.secondsigma = uispinner(app.FilteringTab);
            app.SegmentationTab = uitab(app.TabGroup);
            app.SegmentationTab.Title = 'Segmentation';
            app.keeppoolCheckBox_2 = uicheckbox(app.SegmentationTab);
            app.keeppoolCheckBox_2.Text = 'keep pool';
            app.displayfiguresCheckBox_2 = uicheckbox(app.SegmentationTab);
            app.displayfiguresCheckBox_2.Text = 'display figures';
            app.runSegmentSpots = uibutton(app.SegmentationTab, 'push');
            app.runSegmentSpots.ButtonPushedFcn = createCallbackFcn(app, @runSegmentSpotsPushed, true);
            app.runSegmentSpots.Text = 'Run segmentation';
            app.numberofworkersEditField_2Label = uilabel(app.SegmentationTab);
            app.numberofworkersEditField_2Label.Text = 'number of workers';
            app.numberofworkersEditField_2 = uieditfield(app.SegmentationTab, 'numeric');
            app.numberofworkersEditField_2.Limits = [0 Inf];
            app.numberofworkersEditField_2.RoundFractionalValues = 'on';
            app.numberofworkersEditField_2.Value = 8;
            app.thresholdGUICheckBox = uicheckbox(app.SegmentationTab);
            app.thresholdGUICheckBox.Text = 'threshold GUI';
            app.thresholdGUICheckBox.Value = true;
            app.FrameEditFieldLabel = uilabel(app.SegmentationTab);
            app.FrameEditFieldLabel.Text = 'Frame';
            app.firstFrameEditField = uieditfield(app.SegmentationTab, 'numeric');
            app.firstFrameEditField.UpperLimitInclusive = 'off';
            app.firstFrameEditField.Limits = [1 Inf];
            app.firstFrameEditField.RoundFractionalValues = 'on';
            app.firstFrameEditField.Editable = 'off';
            app.firstFrameEditField.Value = 1;
            app.Label = uilabel(app.SegmentationTab);
            app.Label.Text = '-';
            app.LastFrameEditField = uieditfield(app.SegmentationTab, 'numeric');
            app.LastFrameEditField.Limits = [1 Inf];
            app.LastFrameEditField.RoundFractionalValues = 'on';
            app.LastFrameEditField.Value = Inf;
            app.numberofshadowsDropDownLabel = uilabel(app.SegmentationTab);
            app.numberofshadowsDropDownLabel.Text = 'number of shadows';
            app.numberofshadowsDropDown = uidropdown(app.SegmentationTab);
            app.numberofshadowsDropDown.Items = {'0', '1', '2'};
            app.numberofshadowsDropDown.Value = '2';
            app.IntegralzCheckBox = uicheckbox(app.SegmentationTab);
            app.IntegralzCheckBox.Text = 'Integral z';
            app.IntegralzCheckBox.Value = true;
            app.integrationscaleEditFieldLabel = uilabel(app.SegmentationTab);
            app.integrationscaleEditFieldLabel.Text = 'integration scale';
            app.integrationscaleEditField = uieditfield(app.SegmentationTab, 'numeric');
            app.integrationscaleEditField.Value = 1;
            app.Thresholdch1EditFieldLabel = uilabel(app.SegmentationTab);
            app.Thresholdch1EditFieldLabel.Text = 'Threshold (ch1)';
            app.Thresholdch1EditField = uieditfield(app.SegmentationTab, 'numeric');
            app.Thresholdch2EditFieldLabel = uilabel(app.SegmentationTab);
            app.Thresholdch2EditFieldLabel.Text = 'Threshold (ch2)';
            app.Thresholdch2EditField = uieditfield(app.SegmentationTab, 'numeric');
            app.ifapplicableLabel = uilabel(app.SegmentationTab);
            app.ifapplicableLabel.Text = '(if applicable)';
            app.segAxes = uiaxes(app.SegmentationTab);
            app.segAxes.XTick = [];
            app.segAxes.YTick = [];
            app.segAxes.ZTick = [];
            app.TrackingTab = uitab(app.TabGroup);
            app.TrackingTab.Title = 'Tracking';
            app.runTrackmRNADynamics = uibutton(app.TrackingTab, 'push');
            app.runTrackmRNADynamics.ButtonPushedFcn = createCallbackFcn(app, @runTrackmRNADynamicsButtonPushed, true);
            app.runTrackmRNADynamics.Text = 'Run tracking';
            app.AxisregistrationTab = uitab(app.TabGroup);
            app.AxisregistrationTab.Title = 'Axis registration';
            app.runFindAPAxis = uibutton(app.AxisregistrationTab, 'push');
            app.runFindAPAxis.ButtonPushedFcn = createCallbackFcn(app, @runFindAPAxisButtonPushed, true);
            app.runFindAPAxis.Text = 'Find AP Axis';
            app.runAddParticlePosition = uibutton(app.AxisregistrationTab, 'push');
            app.runAddParticlePosition.ButtonPushedFcn = createCallbackFcn(app, @runAddParticlePositionButtonPushed, true);
            app.runAddParticlePosition.Text = 'Add particle positions';
            app.CorrectAxisCheckBox = uicheckbox(app.AxisregistrationTab);
            app.CorrectAxisCheckBox.Text = 'Correct Axis';
            app.ManualAlignmentCheckBox = uicheckbox(app.AxisregistrationTab);
            app.ManualAlignmentCheckBox.Text = 'Manual Alignment';
            app.NuclearsegmentationandtrackingTab = uitab(app.TabGroup);
            app.NuclearsegmentationandtrackingTab.Title = 'Nuclear segmentation and tracking';
            app.DataStatusTab = uitab(app.TabGroup);
            app.DataStatusTab.Title = 'Data Status';
            app.Panel = uipanel(app.LivemRNAAnalysisUIFigure);
            app.PrefixEditField_4Label = uilabel(app.Panel);
            app.PrefixEditField_4Label.Text = 'Prefix';
            app.PrefixEditField_4 = uieditfield(app.Panel, 'text');
            app.PrefixEditField_4.ValueChangedFcn = createCallbackFcn(app, @prefixLoaded, true);
            app.DataexportedLabel = uilabel(app.Panel);
            app.DataexportedLabel.Text = 'Data exported';
            app.DataexportedLamp = uilamp(app.Panel);
            app.DatafilteredLampLabel = uilabel(app.Panel);
            app.DatafilteredLampLabel.Text = 'Data filtered';
            app.DatafilteredLamp = uilamp(app.Panel);
            app.DatasegmentedLampLabel = uilabel(app.Panel);
            app.DatasegmentedLampLabel.Text = 'Data segmented';
            app.DatasegmentedLamp = uilamp(app.Panel);
            app.DatatrackedLampLabel = uilabel(app.Panel);
            app.DatatrackedLampLabel.Text = 'Data tracked';
            app.DatatrackedLamp = uilamp(app.Panel);
            app.LoadFrameInfoButton = uibutton(app.Panel, 'push');
            app.LoadFrameInfoButton.Enable = 'off';
            app.LoadFrameInfoButton.Text = 'Load FrameInfo';
            app.ExperimenttypeTextAreaLabel = uilabel(app.Panel);
            app.ExperimenttypeTextAreaLabel.Text = 'Experiment type';
            app.Experimenttype = uitextarea(app.Panel);
            app.Experimenttype.Editable = 'off';
            app.RefreshButton = uibutton(app.Panel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @prefixLoaded, true);
            app.RefreshButton.Text = 'Refresh';
            app.LoadlogfileButton = uibutton(app.Panel, 'push');
            app.LoadlogfileButton.Enable = 'off';
            app.LoadlogfileButton.Text = 'Load log file';
            app.Channel1Label = uilabel(app.Panel);
            app.Channel1Label.Text = 'Channel 1';
            app.ch1Label = uitextarea(app.Panel);
            app.ch1Label.Editable = 'off';
            app.Channel3Label = uilabel(app.Panel);
            app.Channel3Label.Text = 'Channel 3';
            app.ch3Label = uitextarea(app.Panel);
            app.ch3Label.Editable = 'off';
            app.Channel2Label = uilabel(app.Panel);
            app.Channel2Label.Text = 'Channel 2';
            app.ch2Label = uitextarea(app.Panel);
            app.ch2Label.Editable = 'off';
            app.APaxisregisteredLampLabel = uilabel(app.Panel);
            app.APaxisregisteredLampLabel.Text = 'AP axis registered';
            app.APaxisregisteredLamp = uilamp(app.Panel);
            app.ParticlesregisteredtoaxisLampLabel = uilabel(app.Panel);
            app.ParticlesregisteredtoaxisLampLabel.Text = 'Particles registered to axis';
            app.ParticlesregisteredtoaxisLamp = uilamp(app.Panel);
        end
    end

    methods (Access = public)

        % Construct app
        function app = livemRNAApp(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LivemRNAAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LivemRNAAnalysisUIFigure)
        end
    end
end